classdef Project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        UIAxes                 matlab.ui.control.UIAxes
        Select_ImageButton     matlab.ui.control.Button
        BlueObjectLabel        matlab.ui.control.Label
        RedObjectLabel         matlab.ui.control.Label
        UIAxes2                matlab.ui.control.UIAxes
        UIAxes_3               matlab.ui.control.UIAxes
        TotalRedObjectsLabel   matlab.ui.control.Label
        TotalBlueObjectsLabel  matlab.ui.control.Label
        UIAxes3                matlab.ui.control.UIAxes
        ObjectsDetectiononthebasisofcolorLabel  matlab.ui.control.Label
    end

    methods (Access = private)

        % Code that executes after component creation
        function mouseClickCallback(app)

     
        end

        % Button pushed function: Select_ImageButton
        function Select_ImageButtonPushed(app, event)
           [fileName, pathName] = uigetfile({'*.jpg;*.png;*.jpeg', 'Image files'}, 'Select Image');

            % Check if a file was selected
            if isequal(fileName, 0)
                return; % No file selected, do nothing
            end
            
            % Read the selected image
            imagePath = fullfile(pathName, fileName);
            image = im2double(imread(imagePath));
            
            % Convert RGB image to HSV color space
            I = rgb2hsv(image);
            
            % Define thresholds values for blue color
            blueChannel1Min = 0.544;
            blueChannel1Max = 0.677;
            blueChannel2Min = 0.513;
            blueChannel2Max = 1.000;
            blueChannel3Min = 0.000;
            blueChannel3Max = 1.000;
            
            % Create blue mask based on chosen histogram thresholds
            blueMask = (I(:,:,1) >= blueChannel1Min) & (I(:,:,1) <= blueChannel1Max) & ...
                       (I(:,:,2) >= blueChannel2Min) & (I(:,:,2) <= blueChannel2Max) & ...
                       (I(:,:,3) >= blueChannel3Min) & (I(:,:,3) <= blueChannel3Max);
            
            % Define thresholds for red color
            redChannel1Min = 0.954;
            redChannel1Max = 0.009;
            redChannel2Min = 0.709;
            redChannel2Max = 1.000;
            redChannel3Min = 0.000;
            redChannel3Max = 1.000;
            
            % Create red mask based on chosen histogram thresholds
            redMask = ((I(:,:,1) >= redChannel1Min) | (I(:,:,1) <= redChannel1Max)) & ...
                      (I(:,:,2) >= redChannel2Min) & (I(:,:,2) <= redChannel2Max) & ...
                      (I(:,:,3) >= redChannel3Min) & (I(:,:,3) <= redChannel3Max);
            
            % Apply morphological operations
            se = strel('disk', 4); %creates a morphological structuring element (disk with a radius of 4 pixels)
            afterOpeningBlue = imopen(blueMask, se);
            afterClosingBlue = imclose(afterOpeningBlue, se);
            afterOpeningRed = imopen(redMask, se);
            afterClosingRed = imclose(afterOpeningRed, se);
            
            % Object Counting
            [~, blueObjectsCount] = bwlabel(afterClosingBlue);
            [~, redObjectsCount] = bwlabel(afterClosingRed);
            
            % Display results
            imshow(image, 'Parent', app.UIAxes);
           
            imshow(afterClosingBlue, 'Parent', app.UIAxes3);

            imshow(afterClosingRed, 'Parent', app.UIAxes2);

            imshow(image, 'Parent', app.UIAxes_3);
            hold(app.UIAxes_3, 'on');
            
            % Display bounding boxes around detected blue objects
            [Bblue, Lblue] = bwboundaries(afterClosingBlue, 'noholes');
            for k = 1:length(Bblue)
                boundary = Bblue{k};
                plot(app.UIAxes_3, boundary(:,2), boundary(:,1), 'b', 'LineWidth', 2);
            end
            
            % Display bounding boxes around detected red objects
            [Bred, Lred] = bwboundaries(afterClosingRed, 'noholes');
            for k = 1:length(Bred)
                boundary = Bred{k};
                plot(app.UIAxes_3, boundary(:,2), boundary(:,1), 'r', 'LineWidth', 2);
            end
            hold(app.UIAxes_3, 'off');
            
            % Debugging: Display counts
            disp(['blueObjectsCount: ', num2str(blueObjectsCount)]);
            disp(['redObjectsCount: ', num2str(redObjectsCount)]);
            
            % Ensure the counts are scalars (which they should be)
            if ~isscalar(blueObjectsCount)
                error('blueObjectsCount is not a scalar.');
            end
            
            if ~isscalar(redObjectsCount)
                error('redObjectsCount is not a scalar.');
            end
            
            % Convert counts to strings
            blueCountStr = num2str(blueObjectsCount);
            redCountStr = num2str(redObjectsCount);
            
            % Display counts
            app.BlueObjectLabel.Text = ['', blueCountStr];
            app.RedObjectLabel.Text = ['', redCountStr];

        end

        % Close request function: UIFigure
        function figureClickCallback(app, event)
            delete(app)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @figureClickCallback, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Select_ImageButton = uibutton(app.UIFigure, 'push');
            app.Select_ImageButton.ButtonPushedFcn = createCallbackFcn(app, @Select_ImageButtonPushed, true);
            app.Select_ImageButton.Text = 'Select_Image';
            app.BlueObjectLabel = uilabel(app.UIFigure);
            app.BlueObjectLabel.Text = 'BlueObject';
            app.RedObjectLabel = uilabel(app.UIFigure);
            app.RedObjectLabel.Text = 'RedObject';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Red Objects')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Segmentation')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.TotalRedObjectsLabel = uilabel(app.UIFigure);
            app.TotalRedObjectsLabel.Text = 'Total Red Objects:';
            app.TotalBlueObjectsLabel = uilabel(app.UIFigure);
            app.TotalBlueObjectsLabel.Text = 'Total Blue Objects:';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.ObjectsDetectiononthebasisofcolorLabel = uilabel(app.UIFigure);
            app.ObjectsDetectiononthebasisofcolorLabel.Text = 'Objects Detection on the basis of color';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Project

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @mouseClickCallback)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end